classdef AppSA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SynthesisAndAnalysisUIFigure  matlab.ui.Figure
        Menu_file                     matlab.ui.container.Menu
        Menu_exit                     matlab.ui.container.Menu
        Menu_settings                 matlab.ui.container.Menu
        Menu_LogOn                    matlab.ui.container.Menu
        Menu_ClearLog                 matlab.ui.container.Menu
        Menu_generationSettings       matlab.ui.container.Menu
        Menu_removeFiles              matlab.ui.container.Menu
        Panel_synthesis               matlab.ui.container.Panel
        Button_count                  matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        DropDown_synthesis_module     matlab.ui.control.DropDown
        Label_2                       matlab.ui.control.Label
        DropDown_synthesis_functions  matlab.ui.control.DropDown
        Button_generateModel          matlab.ui.control.Button
        CheckBox_open                 matlab.ui.control.CheckBox
        Label_9                       matlab.ui.control.Label
        DropDown_genModelConfig       matlab.ui.control.DropDown
        Panel_input                   matlab.ui.container.Panel
        Label_3                       matlab.ui.control.Label
        EditField_input_num           matlab.ui.control.EditField
        Label_4                       matlab.ui.control.Label
        EditField_input_den           matlab.ui.control.EditField
        Button_load_input             matlab.ui.control.Button
        Panel_output                  matlab.ui.container.Panel
        Label_5                       matlab.ui.control.Label
        EditField_output_num          matlab.ui.control.EditField
        Label_6                       matlab.ui.control.Label
        EditField_output_den          matlab.ui.control.EditField
        Button_save_output            matlab.ui.control.Button
        Panel_analasys                matlab.ui.container.Panel
        Button_analyze                matlab.ui.control.Button
        Label_7                       matlab.ui.control.Label
        DropDown_analysis_module      matlab.ui.control.DropDown
        Label_8                       matlab.ui.control.Label
        DropDown_analysis_functions   matlab.ui.control.DropDown
        Panel_log                     matlab.ui.container.Panel
        TextArea_Log                  matlab.ui.control.TextArea
        Panel_inputFields             matlab.ui.container.Panel
    end

    
    properties (Access = public)
        controllerObjSynthesis
        controllerObjAnalysis
        controllerObjService
        LoadedClasses
        LoadedSynthesisFunctions
        LoadedAnalysisFunctions
        InputData
        MsBox
        GUILayOut = false;
        ResSynthesis
        ResAnalysis
        GUILogger
        FileLogger
        LogPosition = [555,18,203,630]
        fname
        Path
    end
    
    methods (Access = private)
        
        function Index = getDropDownIndex(app,DropDown)
            switch DropDown
                case {app.DropDown_synthesis_module, app.DropDown_analysis_module, app.DropDown_genModelConfig}
                    Index = find(strcmp(app.LoadedClasses,DropDown.Value));
                case app.DropDown_synthesis_functions
                    Index = find(strcmp(app.LoadedSynthesisFunctions,DropDown.Value));
                case app.DropDown_analysis_functions
                    Index = find(strcmp(app.LoadedAnalysisFunctions,DropDown.Value));
            end
        end
        
        function InputTF = readInputTF(app)
            try
                InputNum = str2num(app.EditField_input_num.Value);
                InputDen = str2num(app.EditField_input_den.Value);
                InputTF = tf(InputNum,InputDen);
            catch ME
                app.MsBox = msgbox(ME.message,'Error');
            end
        end
        
        function loadFile(app,fileChooser)
            try
                app.GUILogger.Log('loading file...')
                [file,folder,status] = fileChooser.chooseFile('*.*');
                if ~isequal(file,0)
                    app.InputData = load([folder '/' file]);
                    assignin('base','InputData',app.InputData);
                    app.EditField_input_num.Value = num2str(app.InputData.TFplant.num{1});
                    app.EditField_input_den.Value = num2str(app.InputData.TFplant.den{1});
                    app.GUILogger.Log('file is loaded')
                end
            catch ME
                app.MsBox = msgbox(ME.message,'Error');
            end
        end
        
        function log(app,text)
            if ~isempty(app.GUILogger)
                app.GUILogger.Log(text);
            else
                app.TextArea_Log.Value = 'gui logger is not initialized';
            end
            if ~isempty(app.FileLogger)
                app.FileLogger.Log(text);
            else
                app.TextArea_Log.Value = 'file logger is not initialized';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, fileChooser)
            %initialize service controller
            app.controllerObjService = AppController(app,'ModelConfigurations');
            app.controllerObjService.initializePath();
            app.controllerObjService.initialize(app.DropDown_genModelConfig);
            
            %inititalize Logger
            app.GUILogger = GUILogger(app.TextArea_Log);                        
            app.FileLogger = FileLogger('log.txt');
            app.log('gui logger is initialized');
            app.log('file logger is initialized');            
            
            %initilaization of class to load input params (mock for tests)
            if nargin == 1
                fileChooser = FileChooser;
            end
            
            app.Button_load_input.ButtonPushedFcn = @(src,evt)loadFile(app,fileChooser);
            
            %Synthesis connection
            app.log('connecting modules...');
            app.controllerObjSynthesis = AppController(app,'SynthesisModules');
            app.controllerObjSynthesis.initialize(app.DropDown_synthesis_module);
            ModuleValueIndex = app.getDropDownIndex(app.DropDown_synthesis_module);
            app.controllerObjSynthesis.setFunctionDropDown(app.LoadedClasses(ModuleValueIndex,2),...
                app.DropDown_synthesis_functions);
            app.controllerObjSynthesis.setGUILayOut(app.LoadedClasses(ModuleValueIndex,2));
            app.log('synthesis modules connected');
            
            %Analysis connection
            app.controllerObjAnalysis = AppController(app,'AnalysisModules');
            app.controllerObjAnalysis.initialize(app.DropDown_analysis_module);
            AnalysisValueIndex = app.getDropDownIndex(app.DropDown_analysis_module);
            app.controllerObjAnalysis.setFunctionDropDown(app.LoadedClasses(AnalysisValueIndex,2),...
                app.DropDown_analysis_functions);
            app.log('analysis modules connected');
            
            app.controllerObjSynthesis.createInputFields(app.LoadedClasses(ModuleValueIndex,2));
        end

        % Value changed function: DropDown_synthesis_module
        function DropDown_synthesis_moduleValueChanged(app, event)
            valueIndex = app.getDropDownIndex(app.DropDown_synthesis_module);
            app.controllerObjSynthesis.setFunctionDropDown(app.LoadedClasses(valueIndex,2),...
                app.DropDown_synthesis_functions);
            app.controllerObjSynthesis.setGUILayOut(app.LoadedClasses(valueIndex,2));
            
            %clear current inputs and create new
            app.controllerObjSynthesis.createInputFields(app.LoadedClasses(valueIndex,2));
            app.log('synthesis module changed to');
            app.log(char(app.LoadedClasses(valueIndex,2)));
        end

        % Button pushed function: Button_count
        function Button_countPushed(app, event)
            app.log('synthesing...')
            ModuleName = app.DropDown_synthesis_module.Value;
            ModuleIndex = app.getDropDownIndex(app.DropDown_synthesis_module);
            app.log(['module: ' app.DropDown_synthesis_module.Value]);
            
            FunctionName = app.DropDown_synthesis_functions.Value;
            FunctionIndex = app.getDropDownIndex(app.DropDown_synthesis_functions);
            app.log(['function: ' app.DropDown_synthesis_functions.Value]);
            
            app.InputData.TFplant = app.readInputTF();
            app.controllerObjSynthesis.readInputDataFromGUI(app.LoadedClasses(ModuleIndex,2));
            classObj = app.controllerObjSynthesis.createObject(app.LoadedClasses(ModuleIndex,2),app.InputData);
            app.ResSynthesis = app.controllerObjSynthesis.callFunction(classObj,...
                app.LoadedSynthesisFunctions(FunctionIndex,2));
            app.log('synthesing done');
            
            app.log('writing results...');
            GUIWriter.write(app,app.ResSynthesis);            
            FileWriter.write([app.Path '/result.mat'],app.ResSynthesis);
            app.log('results are writed');
        end

        % Value changed function: DropDown_analysis_module
        function DropDown_analysis_moduleValueChanged(app, event)
            valueIndex = app.getDropDownIndex(app.DropDown_analysis_module);
            app.controllerObjAnalysis.setFunctionDropDown(app.LoadedClasses(valueIndex,2),...
                app.DropDown_analysis_functions);
            app.controllerObjAnalysis.setGUILayOut(app.LoadedClasses(valueIndex,2));
            app.log('analysis module changed to');
            app.log(char(app.LoadedClasses(valueIndex,2)));
        end

        % Button pushed function: Button_analyze
        function Button_analyzePushed(app, event)
            app.log('analysing...')
            if isempty(app.ResSynthesis)
                app.MsBox = msgbox('No results to analyze','Error');
            else
                ModuleName = app.DropDown_analysis_module.Value;
                ModuleIndex = app.getDropDownIndex(app.DropDown_analysis_module);
                
                FunctionName = app.DropDown_analysis_functions.Value;
                FunctionIndex = app.getDropDownIndex(app.DropDown_analysis_functions);
                
                classObj = app.controllerObjAnalysis.createObject(app.LoadedClasses(ModuleIndex,2),...
                    feedback(app.ResSynthesis*app.InputData.TFplant,1));
                app.ResAnalysis = app.controllerObjAnalysis.callFunction(classObj,...
                    app.LoadedAnalysisFunctions(FunctionIndex,2));
                app.log('analysing done');
                
                GUIWriter.write(app,app.ResAnalysis);
            end
        end

        % Menu selected function: Menu_LogOn
        function Menu_LogOnSelected(app, event)
            if strcmp(app.Menu_LogOn.Checked,'on')
                app.controllerObjService.hideLog();
                app.log('log off');
            else
                app.controllerObjService.showLog();
                app.log('log on');
            end
        end

        % Menu selected function: Menu_exit
        function Menu_exitSelected(app, event)
            app.delete;
        end

        % Button pushed function: Button_generateModel
        function Button_generateModelPushed(app, event)
            app.log('generating model...');
            InputData.TFPlant = app.InputData.TFplant;
            InputData.Res = app.ResSynthesis;
            ConfigIndex = app.getDropDownIndex(app.DropDown_genModelConfig);
            app.controllerObjService.generateModel(InputData,...
                app.LoadedClasses(ConfigIndex,2),...
                app.CheckBox_open.Value);
            app.log('generating done');
        end

        % Menu selected function: Menu_removeFiles
        function Menu_removeFilesSelected(app, event)
            app.log('clearing Models folder');
            app.controllerObjService.clearDirectory(app.Path);
            app.log('clearing done');
        end

        % Menu selected function: Menu_ClearLog
        function Menu_ClearLogSelected(app, event)
            app.controllerObjService.clearLogs();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SynthesisAndAnalysisUIFigure = uifigure('Visible', 'off');
            app.SynthesisAndAnalysisUIFigure.Name = 'SynthesisAndAnalysis';
            app.Menu_file = uimenu(app.SynthesisAndAnalysisUIFigure);
            app.Menu_file.Text = 'Файл';
            app.Menu_exit = uimenu(app.Menu_file);
            app.Menu_exit.MenuSelectedFcn = createCallbackFcn(app, @Menu_exitSelected, true);
            app.Menu_exit.Text = 'Выход';
            app.Menu_settings = uimenu(app.SynthesisAndAnalysisUIFigure);
            app.Menu_settings.Text = 'Свойства';
            app.Menu_LogOn = uimenu(app.Menu_settings);
            app.Menu_LogOn.MenuSelectedFcn = createCallbackFcn(app, @Menu_LogOnSelected, true);
            app.Menu_LogOn.Text = 'Лог';
            app.Menu_ClearLog = uimenu(app.Menu_settings);
            app.Menu_ClearLog.MenuSelectedFcn = createCallbackFcn(app, @Menu_ClearLogSelected, true);
            app.Menu_ClearLog.Text = 'Очистить лог';
            app.Menu_generationSettings = uimenu(app.SynthesisAndAnalysisUIFigure);
            app.Menu_generationSettings.Text = 'Генерация';
            app.Menu_removeFiles = uimenu(app.Menu_generationSettings);
            app.Menu_removeFiles.MenuSelectedFcn = createCallbackFcn(app, @Menu_removeFilesSelected, true);
            app.Menu_removeFiles.Text = 'Очистить директорию';
            app.Panel_synthesis = uipanel(app.SynthesisAndAnalysisUIFigure);
            app.Panel_synthesis.Title = 'Синтез';
            app.Button_count = uibutton(app.Panel_synthesis, 'push');
            app.Button_count.ButtonPushedFcn = createCallbackFcn(app, @Button_countPushed, true);
            app.Button_count.Text = 'Считать';
            app.Label = uilabel(app.Panel_synthesis);
            app.Label.Text = 'Модуль';
            app.DropDown_synthesis_module = uidropdown(app.Panel_synthesis);
            app.DropDown_synthesis_module.Items = {};
            app.DropDown_synthesis_module.ValueChangedFcn = createCallbackFcn(app, @DropDown_synthesis_moduleValueChanged, true);
            app.DropDown_synthesis_module.Value = {};
            app.Label_2 = uilabel(app.Panel_synthesis);
            app.Label_2.Text = 'Функция';
            app.DropDown_synthesis_functions = uidropdown(app.Panel_synthesis);
            app.DropDown_synthesis_functions.Items = {};
            app.DropDown_synthesis_functions.Value = {};
            app.Button_generateModel = uibutton(app.Panel_synthesis, 'push');
            app.Button_generateModel.ButtonPushedFcn = createCallbackFcn(app, @Button_generateModelPushed, true);
            app.Button_generateModel.Text = {'Сгенерировать'; 'модель'};
            app.CheckBox_open = uicheckbox(app.Panel_synthesis);
            app.CheckBox_open.Text = 'Открыть';
            app.Label_9 = uilabel(app.Panel_synthesis);
            app.Label_9.Text = 'Конфигурация';
            app.DropDown_genModelConfig = uidropdown(app.Panel_synthesis);
            app.DropDown_genModelConfig.Items = {};
            app.DropDown_genModelConfig.Value = {};
            app.Panel_input = uipanel(app.SynthesisAndAnalysisUIFigure);
            app.Panel_input.Title = 'Входные параметры';
            app.Label_3 = uilabel(app.Panel_input);
            app.Label_3.Text = 'Числитель';
            app.EditField_input_num = uieditfield(app.Panel_input, 'text');
            app.EditField_input_num.Value = '1';
            app.Label_4 = uilabel(app.Panel_input);
            app.Label_4.Text = 'Знаменатель';
            app.EditField_input_den = uieditfield(app.Panel_input, 'text');
            app.EditField_input_den.Value = '1 0';
            app.Button_load_input = uibutton(app.Panel_input, 'push');
            app.Button_load_input.Text = 'Загрузить';
            app.Panel_output = uipanel(app.SynthesisAndAnalysisUIFigure);
            app.Panel_output.Title = 'Выходные параметры';
            app.Label_5 = uilabel(app.Panel_output);
            app.Label_5.Text = 'Числитель';
            app.EditField_output_num = uieditfield(app.Panel_output, 'text');
            app.Label_6 = uilabel(app.Panel_output);
            app.Label_6.Text = 'Знаменатель';
            app.EditField_output_den = uieditfield(app.Panel_output, 'text');
            app.Button_save_output = uibutton(app.Panel_output, 'push');
            app.Button_save_output.Text = 'Сохранить';
            app.Panel_analasys = uipanel(app.SynthesisAndAnalysisUIFigure);
            app.Panel_analasys.Title = 'Анализ';
            app.Button_analyze = uibutton(app.Panel_analasys, 'push');
            app.Button_analyze.ButtonPushedFcn = createCallbackFcn(app, @Button_analyzePushed, true);
            app.Button_analyze.Text = 'Считать';
            app.Label_7 = uilabel(app.Panel_analasys);
            app.Label_7.Text = 'Модуль';
            app.DropDown_analysis_module = uidropdown(app.Panel_analasys);
            app.DropDown_analysis_module.Items = {};
            app.DropDown_analysis_module.ValueChangedFcn = createCallbackFcn(app, @DropDown_analysis_moduleValueChanged, true);
            app.DropDown_analysis_module.Value = {};
            app.Label_8 = uilabel(app.Panel_analasys);
            app.Label_8.Text = 'Функция';
            app.DropDown_analysis_functions = uidropdown(app.Panel_analasys);
            app.DropDown_analysis_functions.Items = {};
            app.DropDown_analysis_functions.Value = {};
            app.Panel_log = uipanel(app.SynthesisAndAnalysisUIFigure);
            app.Panel_log.Title = 'Лог';
            app.Panel_log.Visible = 'off';
            app.TextArea_Log = uitextarea(app.Panel_log);
            app.TextArea_Log.Editable = 'off';
            app.Panel_inputFields = uipanel(app.SynthesisAndAnalysisUIFigure);
            app.Panel_inputFields.Title = 'Входные параметры';
            % Show the figure after all components are created
            app.SynthesisAndAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppSA(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SynthesisAndAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SynthesisAndAnalysisUIFigure)
        end
    end
end